classdef Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        HighestPointTimeEditField       matlab.ui.control.NumericEditField
        HighestPointTimeEditFieldLabel  matlab.ui.control.Label
        DisplacementEditField           matlab.ui.control.NumericEditField
        DisplacementEditFieldLabel      matlab.ui.control.Label
        BallSelectionPanel              matlab.ui.container.Panel
        BasketballLabel                 matlab.ui.control.Label
        Button_3                        matlab.ui.control.Button
        M062KGLabel                     matlab.ui.control.Label
        M042KGLabel                     matlab.ui.control.Label
        SoccerballLabel                 matlab.ui.control.Label
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        M0145KGLabel                    matlab.ui.control.Label
        BaseballLabel                   matlab.ui.control.Label
        SelectedBallImage               matlab.ui.control.Image
        SelectTheBallButton             matlab.ui.control.Button
        length_width                    matlab.ui.control.EditField
        Image                           matlab.ui.control.Image
        AirResistanceCheckBox           matlab.ui.control.CheckBox
        SimulateButton                  matlab.ui.control.Button
        MaxHeightEditField              matlab.ui.control.NumericEditField
        MaxHeightEditFieldLabel         matlab.ui.control.Label
        FlightTimeEditField             matlab.ui.control.NumericEditField
        FlightTimeEditFieldLabel        matlab.ui.control.Label
        InitialY0EditField              matlab.ui.control.NumericEditField
        InitialY0EditFieldLabel         matlab.ui.control.Label
        SelectPlanetDropDown            matlab.ui.control.DropDown
        SelectPlanetDropDownLabel       matlab.ui.control.Label
        InitalAbcisseX0EditField        matlab.ui.control.NumericEditField
        InitalAbcisseX0EditFieldLabel   matlab.ui.control.Label
        InitialAngleEditField           matlab.ui.control.NumericEditField
        InitialAngleLabel               matlab.ui.control.Label
        InitialVelocityEditField        matlab.ui.control.NumericEditField
        InitialVelocityEditFieldLabel   matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        SelectedBall='Baseball' % Description
    end
    
    
    methods (Access = private)
        
        function [x,y,tf,hmax,th] = TrajectoryFunction_AirResistance(app,x0,y0,v0,theta,planet,m)
            g=planet.g;
            cd=planet.drag_coefficient;%DRAG COEFFICIENT
            rho=planet.air_density;
            A=0.043;%SURFACE OF THE BALL
            c=0.5*cd*rho*A;
            vt=(m*g)/c;
            %INITIAL VELOCITY
            v0x = v0*cosd(theta);
            v0y = v0*sind(theta);
            %TIME OF THE HIGHEST POINT
            th=(vt/g)*log(1+v0y/vt);
            %MAXIMUM HEIGHT
            hmax=(vt/g)*(v0y+vt)*(1-exp(-(g/vt)*th))-vt*th+y0;
            save Variables.mat g c m 
            if v0<100
                b=30;
            elseif v0>=100 && v0<300
                b=100;
            else
                b=150;
            end
            [Y,t]= RK4(app,@f,x0,v0x,y0,v0y,0,b,100);
            y=Y(:,3);
            x=Y(:,1);
            %DETERMINE XMAX
            i=1;
            try
            while y(i)>=0 
                xmax=x(i);
                i=i+1;
            end
            catch
               %In Case of a so big velocity
                errordlg("The Initial velocity is to big!");
                x=0;y=0;tf=0;hmax=0;
                return;
            end
            %TIME FLIGHT
            tf=(-vt/g)*log(1-((g*(xmax-x0))/(v0x*vt)));
            if isnan(tf)
                %in case of an angle of 90 degre weh have to take this
                %approximation
                tf=2*v0y/g;
            end
            
            %CUT THE VECTORS X AND Y TO ELIMINATE THE NEGATIVE VALUES
            y=y(1:i);
            x=x(1:i);
            %i c'est l'indice pour xmax
            
        end
        
       
        function [y,t]=RK4(app,fx,x0,v0x,y0,v0y,a,b,N)
            clc; close all;
            h=(b-a)/N;
            y = zeros(N,4);
            y(1,:)=[x0 v0x y0 v0y];t(1)=a;
            for i=1:N
            t(i+1)=a+i*h;
            k1=feval(fx,app,t(i),y(i,:));
            k2=feval(fx,app,t(i)+h/2,y(i,:)+(h/2).*k1');
            k3=feval(fx,app,t(i)+h/2,y(i,:)+(h/2).*k2');
            k4=feval(fx,app,t(i)+h,y(i,:)+h.*k3');
            y(i+1,:)=y(i,:)+(h/6)*(k1+2*k2+2*k3+k4)';
            end
        end
        
        function PlotTrajectory(app,x,y)
            xlim(app.UIAxes,[0,max(x)*1.1+1]);
            ylim(app.UIAxes,[0,max(y)*1.1+1]);
            xlabel(app.UIAxes,'X position en(m)');
            ylabel(app.UIAxes,'Y position en(m)')
            % Create an animated line object for the animation
            ax=app.UIAxes;
            ball = animatedline(ax, 'Marker', 'o', 'MarkerSize', 5,'Color','[0.36,0.76,0.36]');
            for i = 1:length(x)
               % Update the animated line with new points
               if isvalid(ball)
               addpoints(ball, x(i), y(i));
               drawnow;
               pause(0.05); % Adjust the pause duration to control the animation speed
               else
               break % stop trying to add points
               end
            end
            end
        function planet = GetPlanet(app,planet_name)
            planets_info;
           switch planet_name
               case 'Mercury'
                   planet=mercury;
               case 'Earth'
                   planet=earth;
               case 'Venus'
                   planet=venus;
               case 'Mars'
                   planet=mars;
               case 'Jupiter'
                   planet=jupiter;
               case 'Saturn'
                   planet=saturn;
               case 'Uranus'
                   planet=uranus;
               case 'Neptune'
                   planet=neptune;
           end
        end
        
        function dy=f(app,t,y)
            load Variables;
            dy = zeros(4,1);    % a column vector
            dy(1) = y(2);
            dy(2) = -c*y(2)/m;
            dy(3) = y(4);
            dy(4)=-g-c*y(4)/m;
        end
        
        
        function  ShowImages(app,xmax,ymax)
            if xmax>500 && ymax>139
                app.Image.ImageSource='Images_used\giza_pyramid.png';
                app.Image.Visible=true;
                app.length_width.Value="H=139m , Base=230m";
                app.length_width.Visible=true;
            elseif xmax>200 && ymax>56
                %show the Leaning Tower of Pisa with heigh 56m
                app.Image.ImageSource='Images_used\tower.jpeg';
                app.Image.Visible=true;
                app.length_width.Value="H=56m";
                app.length_width.Visible=true;
            elseif xmax>100 && ymax>12.5
                %show the plane of height 12.5m and length of 39.5m
                app.Image.ImageSource='Images_used\plane.jpeg';
                app.Image.Visible=true;
                app.length_width.Value="H=12.5m , L=39.5m";
                app.length_width.Visible=true;
            elseif xmax>25 && ymax>1.5
                %show the car of height 1.5m and length of 3.5m
                app.Image.ImageSource='Images_used\car.jpeg';
                app.Image.Visible=true;
                app.length_width.Value="H=1.5m , L=3.5m";
                app.length_width.Visible=true;
            else
                app.Image.Visible=false;
                app.Image.ImageSource='';
                app.length_width.Visible=false;
            end
        end
        
        function  DisableImage(app)
            app.Image.Visible=false;
            app.Image.ImageSource='';
            app.length_width.Visible=false;
        end
        
        function ChangeDirectory(app)
            %TO NOT GET an ERROR WE HAVE TO CHANGE THE DIRECTORY 
            scriptPath = fileparts(mfilename('fullpath'));
            cd(scriptPath);
        end
        
        function [x,y,tf,hmax,th]=TrajectoryFunction(app,x0,y0,v0,theta,planet,m)
            g=planet.g;
            c=0;
            %INITIAL VELOCITY
            v0x = v0*cosd(theta);
            v0y = v0*sind(theta);
            
            %time of the flight
            tf=2*v0y/g;
            
            %time of the highest point
            th=tf/2;
            %Maximum Height
            hmax=(v0^2*sind(theta)^2)/(2*g);
            
            save Variables.mat g c m 
            if v0<10
                b=10;
            elseif v0<100
                b=50;
            elseif v0>=100 && v0<300
                b=150;
            else
                b=200;
            end
            [Y,t]= RK4(app,@f,x0,v0x,y0,v0y,0,b,100);
            y=Y(:,3);
            x=Y(:,1);
            %DETERMINE XMAX
            i=1;
            try
            while y(i)>=0 
                xmax=x(i);
                i=i+1;
            end
            catch
                %In Case of a big velocity
                errordlg("The Initial velocity is to big!");
                x=0;y=0;tf=0;hmax=0;
                return;
            end
            %CUT THE VECTORS X AND Y TO ELIMINATE THE NEGATIVE VALUES
            y=y(1:i);
            x=x(1:i);
            %i c'est l'indice pour xmax
        end
        
        function mass = GetMass(app)
            switch app.SelectedBall
                case 'Baseball'
                    mass=0.145;
                case 'Soccerball'
                    mass=0.42;
                case 'Basketball'
                    mass=0.62;
            end
        end
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         app.Image.Visible=false;
         app.length_width.Visible=false;
         ChangeDirectory(app);
         app.SelectedBallImage.ImageSource='Images_used/baseball.png';
         app.Button.Icon='Images_used/baseball.png';
         app.Button_2.Icon='Images_used/soccer.png';
         app.Button_3.Icon='Images_used/basketball.png';
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            DisableImage(app);
            v0=app.InitialVelocityEditField.Value;
            theta=app.InitialAngleEditField.Value;
            x0=app.InitalAbcisseX0EditField.Value;
            y0=app.InitialY0EditField.Value;
            planet_name=app.SelectPlanetDropDown.Value;
            planet=GetPlanet(app,planet_name);
            m=GetMass(app);
            if app.AirResistanceCheckBox.Value==0
                [x,y,tf,hmax,th]=TrajectoryFunction(app,x0,y0,v0,theta,planet,m);
            else
                [x,y,tf,hmax,th]=TrajectoryFunction_AirResistance(app,x0,y0,v0,theta,planet,m);
            end
            app.HighestPointTimeEditField.Value=th;
            app.MaxHeightEditField.Value=hmax;
            app.FlightTimeEditField.Value=tf;
            app.DisplacementEditField.Value=max(x)-x0;
            PlotTrajectory(app,x,y);
            ShowImages(app,max(x),max(y));
            
        end

        % Button pushed function: SelectTheBallButton
        function SelectTheBallButtonPushed(app, event)
            app.BallSelectionPanel.Visible=true;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.SelectedBall='Baseball';
            app.SelectedBallImage.ImageSource='Images_used/baseball.png';
            app.BallSelectionPanel.Visible=false;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.SelectedBall='Soccerball';
            app.SelectedBallImage.ImageSource='Images_used/soccer.png';
            app.BallSelectionPanel.Visible=false;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.SelectedBall='Basketball';
            app.SelectedBallImage.ImageSource='Images_used/basketball.png';
            app.BallSelectionPanel.Visible=false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Projectile Trajectory')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.InitialVelocityEditFieldLabel = uilabel(app.UIFigure);
            app.InitialVelocityEditFieldLabel.Text = 'Initial Velocity';
            app.InitialVelocityEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialAngleLabel = uilabel(app.UIFigure);
            app.InitialAngleLabel.Text = 'Initial Angle';
            app.InitialAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitalAbcisseX0EditFieldLabel = uilabel(app.UIFigure);
            app.InitalAbcisseX0EditFieldLabel.Text = 'Inital Abcisse X0';
            app.InitalAbcisseX0EditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectPlanetDropDownLabel = uilabel(app.UIFigure);
            app.SelectPlanetDropDownLabel.Text = 'Select Planet';
            app.SelectPlanetDropDown = uidropdown(app.UIFigure);
            app.SelectPlanetDropDown.Items = {'Mercury', 'Venus', 'Earth', 'Mars', 'Jupiter', 'Saturn', 'Uranus', 'Neptune'};
            app.SelectPlanetDropDown.Value = 'Mercury';
            app.InitialY0EditFieldLabel = uilabel(app.UIFigure);
            app.InitialY0EditFieldLabel.Text = 'Initial Y0';
            app.InitialY0EditField = uieditfield(app.UIFigure, 'numeric');
            app.FlightTimeEditFieldLabel = uilabel(app.UIFigure);
            app.FlightTimeEditFieldLabel.Text = 'Flight Time';
            app.FlightTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxHeightEditFieldLabel = uilabel(app.UIFigure);
            app.MaxHeightEditFieldLabel.Text = 'Max Height';
            app.MaxHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.AirResistanceCheckBox = uicheckbox(app.UIFigure);
            app.AirResistanceCheckBox.Text = 'Air Resistance';
            app.Image = uiimage(app.UIFigure);
            app.length_width = uieditfield(app.UIFigure, 'text');
            app.SelectTheBallButton = uibutton(app.UIFigure, 'push');
            app.SelectTheBallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTheBallButtonPushed, true);
            app.SelectTheBallButton.Text = 'Select The Ball';
            app.SelectedBallImage = uiimage(app.UIFigure);
            app.SelectedBallImage.ImageSource = 'baseball.png';
            app.BallSelectionPanel = uipanel(app.UIFigure);
            app.BallSelectionPanel.Title = 'Ball Selection';
            app.BallSelectionPanel.Visible = 'off';
            app.BaseballLabel = uilabel(app.BallSelectionPanel);
            app.BaseballLabel.Text = 'Baseball';
            app.M0145KGLabel = uilabel(app.BallSelectionPanel);
            app.M0145KGLabel.Text = 'M=0.145KG';
            app.Button = uibutton(app.BallSelectionPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'baseball.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.BallSelectionPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'soccer.png';
            app.Button_2.Text = '';
            app.SoccerballLabel = uilabel(app.BallSelectionPanel);
            app.SoccerballLabel.Text = {'Soccerball'; ''};
            app.M042KGLabel = uilabel(app.BallSelectionPanel);
            app.M042KGLabel.Text = 'M=0.42KG';
            app.M062KGLabel = uilabel(app.BallSelectionPanel);
            app.M062KGLabel.Text = 'M=0.62KG';
            app.Button_3 = uibutton(app.BallSelectionPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'basketball.png';
            app.Button_3.Text = '';
            app.BasketballLabel = uilabel(app.BallSelectionPanel);
            app.BasketballLabel.Text = 'Basketball';
            app.DisplacementEditFieldLabel = uilabel(app.UIFigure);
            app.DisplacementEditFieldLabel.Text = 'Displacement';
            app.DisplacementEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighestPointTimeEditFieldLabel = uilabel(app.UIFigure);
            app.HighestPointTimeEditFieldLabel.Text = 'Highest Point Time';
            app.HighestPointTimeEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end