classdef mainwindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_2                       matlab.ui.container.Panel
        ResponseLabel                 matlab.ui.control.Label
        TheCorrectAnswerIs45_30Label  matlab.ui.control.Label
        LoadingImage                  matlab.ui.control.Image
        Panel                         matlab.ui.container.Panel
        Button_5                      matlab.ui.control.Button
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        YourAnswerButtonGroup         matlab.ui.container.ButtonGroup
        Button_4                      matlab.ui.control.RadioButton
        Button_3                      matlab.ui.control.RadioButton
        Button                        matlab.ui.control.RadioButton
        Button_2                      matlab.ui.control.RadioButton
        Label                         matlab.ui.control.Label
        Image_4                       matlab.ui.control.Image
        Image_3                       matlab.ui.control.Image
        Image_2                       matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        ExitButton                    matlab.ui.control.Button
        Simulate3DButton              matlab.ui.control.Button
        ProjectileMotionLabel         matlab.ui.control.Label
        Simulate2DButton              matlab.ui.control.Button
    end


    methods (Access = private)
        
        function Simulate3D(app)
            if app.Button_4.Value==1
                app.ResponseLabel.Text='You are Correct!';
                app.ResponseLabel.FontColor='white';
            else
                app.ResponseLabel.Text='You are Wrong!';
                app.ResponseLabel.FontColor='red';
            end
            app.Panel.Visible=false;
            app.Panel_2.Visible=true;
            pause(1);
            run('ProjectileModel.slx');
            pause(2);
            app.Panel_2.Visible=false;
        end
        function ChangeDirectory(app)
            %TO NOT GET an ERROR WE HAVE TO CHANGE THE DIRECTORY 
            scriptPath = fileparts(mfilename('fullpath'));
            cd(scriptPath);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ChangeDirectory(app);
            app.Image.ImageSource='Images_used/angle_90.png';
            app.Image_2.ImageSource='Images_used/angle_70.png';
            app.Image_3.ImageSource='Images_used/angle_60.png';
            app.Image_4.ImageSource='Images_used/angle_45.png';
            app.LoadingImage.ImageSource='Images_used/rolling.gif';
            app.Button_5.Icon='Images_used/back.png';
        end

        % Button pushed function: Simulate2DButton
        function Simulate2DButtonPushed2(app, event)
            run('Project.mlapp');
        end

        % Button pushed function: Simulate3DButton
        function Simulate3DButtonPushed2(app, event)
          app.Panel.Visible=true;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed2(app, event)
            app.delete();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            Simulate3D(app);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.Panel.Visible=false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Simulate2DButton = uibutton(app.UIFigure, 'push');
            app.Simulate2DButton.ButtonPushedFcn = createCallbackFcn(app, @Simulate2DButtonPushed2, true);
            app.Simulate2DButton.Text = {'Simulate 2D'; ''};
            app.ProjectileMotionLabel = uilabel(app.UIFigure);
            app.ProjectileMotionLabel.Text = 'Projectile Motion';
            app.Simulate3DButton = uibutton(app.UIFigure, 'push');
            app.Simulate3DButton.ButtonPushedFcn = createCallbackFcn(app, @Simulate3DButtonPushed2, true);
            app.Simulate3DButton.Text = 'Simulate 3D';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed2, true);
            app.ExitButton.Text = {'Exit'; ''};
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'angle_90_1.png';
            app.Image_2 = uiimage(app.Panel);
            app.Image_2.ImageSource = 'angle_70.png';
            app.Image_3 = uiimage(app.Panel);
            app.Image_3.ImageSource = 'angle_60.png';
            app.Image_4 = uiimage(app.Panel);
            app.Image_4.ImageSource = 'angle_45.png';
            app.Label = uilabel(app.Panel);
            app.Label.Text = 'A target situated 165 meters away from the cannon.what you think the perfect angle is to launch a projectile with an inital velocity of 35m/s, so that it precisely hits the target?';
            app.YourAnswerButtonGroup = uibuttongroup(app.Panel);
            app.YourAnswerButtonGroup.Title = 'Your Answer';
            app.Button_2 = uiradiobutton(app.YourAnswerButtonGroup);
            app.Button_2.Text = '80-70';
            app.Button_2.Value = true;
            app.Button = uiradiobutton(app.YourAnswerButtonGroup);
            app.Button.Text = '90';
            app.Button_3 = uiradiobutton(app.YourAnswerButtonGroup);
            app.Button_3.Text = '60-50';
            app.Button_4 = uiradiobutton(app.YourAnswerButtonGroup);
            app.Button_4.Text = '45-30';
            app.StartButton = uibutton(app.Panel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '60_50';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '90';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '80_70';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '45_30';
            app.Button_5 = uibutton(app.Panel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Visible = 'off';
            app.LoadingImage = uiimage(app.Panel_2);
            app.TheCorrectAnswerIs45_30Label = uilabel(app.Panel_2);
            app.TheCorrectAnswerIs45_30Label.Text = 'The correct angle is between 30 and 45 degrees. Let''s explore the impact of different angles on the cannon''s ability to hit the target accurately and efficiently in the Simulink 3D simulation';
            app.ResponseLabel = uilabel(app.Panel_2);
            app.ResponseLabel.Text = 'Response';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainwindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end